classdef DoAnMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nUIFigure                   matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        NghiemTab                   matlab.ui.container.Tab
        Nghiem_Nhapfp               matlab.ui.control.EditField
        NhpfpEditFieldLabel         matlab.ui.control.Label
        Nghiem_SoLanLap             matlab.ui.control.TextArea
        SlnlpTextAreaLabel          matlab.ui.control.Label
        Nghiem_KetQua               matlab.ui.control.TextArea
        KtqunghimTextAreaLabel      matlab.ui.control.Label
        Nghiem_PhuongPhap           matlab.ui.control.DropDown
        PhngphptmDropDownLabel      matlab.ui.control.Label
        Nghiem_SaiSoChoPhep         matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel    matlab.ui.control.Label
        Nghiem_KhoangPhanLy_b       matlab.ui.control.NumericEditField
        Nghiem_KhoangPhanLy_a       matlab.ui.control.NumericEditField
        KhongphnlyabEditFieldLabel  matlab.ui.control.Label
        Nghiem_NhapPhuongTrinh      matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel   matlab.ui.control.Label
        Nghiem_DoThi                matlab.ui.control.UIAxes
        NoiSuyTab                   matlab.ui.container.Tab
        NoiSuy_y                    matlab.ui.control.EditField
        NoiSuy_x                    matlab.ui.control.EditField
        NhpdliuxyEditFieldLabel_3   matlab.ui.control.Label
        NoiSuy_KetQua               matlab.ui.control.TextArea
        KtqunisuyTextAreaLabel      matlab.ui.control.Label
        NoiSuy_DaThuc               matlab.ui.control.TextArea
        PhngtrnhLabel               matlab.ui.control.Label
        NoiSuy_GiaTriNoiSuy         matlab.ui.control.NumericEditField
        GitrnisuyLabel              matlab.ui.control.Label
        NoiSuy_PhuongPhap           matlab.ui.control.DropDown
        PhngphpnisuyLabel           matlab.ui.control.Label
        NoiSuy_DoThi                matlab.ui.control.UIAxes
        HoiQuyTab                   matlab.ui.container.Tab
        HoiQuy_KetQua               matlab.ui.control.TextArea
        KtqudonLabel                matlab.ui.control.Label
        HoiQuy_PhuongTrinh          matlab.ui.control.TextArea
        PhngtrnhLabel_2             matlab.ui.control.Label
        HoiQuy_PhuongPhap           matlab.ui.control.DropDown
        PhngphphiquyLabel           matlab.ui.control.Label
        HoiQuy_GiaTriSuyDoan        matlab.ui.control.NumericEditField
        GitrcnsuyonLabel            matlab.ui.control.Label
        HoiQuy_x                    matlab.ui.control.NumericEditField
        NhpdliuxyLabel_2            matlab.ui.control.Label
        HoiQuy_y                    matlab.ui.control.NumericEditField
        HoiQuy_DoThi                matlab.ui.control.UIAxes
        DaoHamTab                   matlab.ui.container.Tab
        DaoHam_y                    matlab.ui.control.EditField
        DaoHam_x                    matlab.ui.control.EditField
        NhpdliuxyEditFieldLabel     matlab.ui.control.Label
        DaoHam_KetQuaChinhXac       matlab.ui.control.TextArea
        KtquchnhxcLabel             matlab.ui.control.Label
        DaoHam_KetQua               matlab.ui.control.TextArea
        KtquTextAreaLabel           matlab.ui.control.Label
        DaoHam_PhuongPhap           matlab.ui.control.DropDown
        PhngphpohmLabel             matlab.ui.control.Label
        DaoHam_GiaTriSaiSo          matlab.ui.control.Switch
        GitrsaisSwitchLabel         matlab.ui.control.Label
        DaoHam_GiaTri               matlab.ui.control.NumericEditField
        GitrcnohmLabel              matlab.ui.control.Label
        DaoHam_HamSo                matlab.ui.control.EditField
        NhphmsLabel                 matlab.ui.control.Label
        TichPhanTab                 matlab.ui.container.Tab
        TichPhan_NutNhan            matlab.ui.control.Button
        TichPhan_HamSo              matlab.ui.control.EditField
        NhphmsLabel_2               matlab.ui.control.Label
        TichPhan_b                  matlab.ui.control.EditField
        TichPhan_a                  matlab.ui.control.EditField
        NhpcnabLabel                matlab.ui.control.Label
        TichPhan_y                  matlab.ui.control.EditField
        TichPhan_x                  matlab.ui.control.EditField
        NhpdliuxyEditFieldLabel_2   matlab.ui.control.Label
        TichPhan_KetQua             matlab.ui.control.TextArea
        KtquTextAreaLabel_2         matlab.ui.control.Label
        TichPhan_PhuongPhap         matlab.ui.control.DropDown
        PhngphptchphnLabel          matlab.ui.control.Label
        TichPhan_N                  matlab.ui.control.TextArea
        NhpNLabel                   matlab.ui.control.Label
        GioiThieuTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DaoHam_GiaTri, DaoHam_GiaTriSaiSo, 
        % DaoHam_HamSo, DaoHam_PhuongPhap, DaoHam_x, DaoHam_y
        function DaoHam_GiaTriValueChanged(app, event)
        addpath(fullfile(pwd,'\DoAnDaoHam\')); %Duong dan file ham chuc nang

        DaoHam_x_val = str2double(strsplit(app.DaoHam_x.Value));
        DaoHam_y_val = str2double(strsplit(app.DaoHam_y.Value));
        
        DaoHam_diemdaoham = app.DaoHam_GiaTri.Value;
        DaoHam_phuongphap = app.DaoHam_PhuongPhap.Value;
        DaoHam_saiso = app.DaoHam_GiaTriSaiSo.Value;
        DaoHam_vitri = find(DaoHam_x_val == DaoHam_diemdaoham);
        DaoHam_hamso = app.DaoHam_HamSo.Value;
        DaoHam_fx = str2func(['@(x) ' DaoHam_hamso]);
        DaoHam_ketqua_val = DaoHam_fx(DaoHam_diemdaoham);
        DaoHam_i = DaoHam_vitri;
        DaoHam_h = DaoHam_x_val(DaoHam_i+1)-DaoHam_x_val(DaoHam_i);
        app.DaoHam_KetQuaChinhXac.Value = num2str(DaoHam_ketqua_val);
        disp(DaoHam_i)
        switch DaoHam_phuongphap
            case 'Xấp xỉ tiến'
                app.DaoHam_GiaTriSaiSo.Items = {'O(h)', 'O(h^2)'};
                DaoHam_kq = xapxitien(DaoHam_y_val,DaoHam_i,DaoHam_saiso,DaoHam_h);
            case 'Xấp xỉ lùi'
                app.DaoHam_GiaTriSaiSo.Items = {'O(h)', 'O(h^2)'};
                DaoHam_kq = xapxilui(DaoHam_y_val,DaoHam_i,DaoHam_saiso,DaoHam_h);
            case 'Xấp xỉ trung tâm'
                %Doi sai so cat cut O(h)->O(h^2), O(h^2)->O(h^4)
                app.DaoHam_GiaTriSaiSo.Items = {'O(h^2)', 'O(h^4)'};            
                DaoHam_kq = xapxitrungtam(DaoHam_y_val,DaoHam_i,DaoHam_saiso,DaoHam_h);
        end
                app.DaoHam_KetQua.Value = num2str(DaoHam_kq);
        end

        % Button pushed function: TichPhan_NutNhan
        function TichPhan_NValueChanged2(app, event)
        addpath(fullfile(pwd,'\DoAnTichPhan\')); %Duong dan file ham chuc nang
        
        TichPhan_a_val = str2double(app.TichPhan_a.Value);
        TichPhan_b_val = str2double(app.TichPhan_b.Value);        
        TichPhan_N_val = str2double(app.TichPhan_N.Value); 
        
        if ~isnan(app.TichPhan_x.Value) 
            TichPhan_x_val = cellfun(@eval, strsplit(app.TichPhan_x.Value));
        else
            TichPhan_x_val = NaN;
        end
        if ~isnan(app.TichPhan_y.Value) 
            TichPhan_y_val = cellfun(@eval, strsplit(app.TichPhan_y.Value));
        else
            TichPhan_y_val = NaN;
        end
        TichPhan_PhuongPhap_val = app.TichPhan_PhuongPhap.Value;
        TichPhan_HamSo_val = app.TichPhan_HamSo.Value;
        if ~isnan(TichPhan_HamSo_val)
            TichPhan_fx = str2func(['@(x) ' TichPhan_HamSo_val]);
        else
            TichPhan_fx = NaN;
        end
        switch TichPhan_PhuongPhap_val
        case 'Hình thang'
            TichPhan_kq = TichPhanHinhThang(TichPhan_fx, TichPhan_a_val, ...
            TichPhan_b_val, TichPhan_N_val, TichPhan_x_val, TichPhan_y_val);
        case 'Simpson 1/3'
            TichPhan_kq = TichPhanSimpson1_3(TichPhan_fx, TichPhan_a_val, ...
            TichPhan_b_val, TichPhan_N_val, TichPhan_x_val, TichPhan_y_val);
        case 'Simpson 3/8'
            TichPhan_kq = TichPhanSimpson3_8(TichPhan_fx, TichPhan_a_val, ...
            TichPhan_b_val, TichPhan_N_val, TichPhan_x_val, TichPhan_y_val);
        end
            disp(TichPhan_kq)
            app.TichPhan_KetQua.Value = num2str(TichPhan_kq);    
        end

        % Callback function: NoiSuy_DaThuc, NoiSuy_DoThi, NoiSuy_KetQua, 
        % NoiSuy_PhuongPhap
        function NoiSuy_DaThucValueChanged(app, event)

addpath(fullfile(pwd,'\DoAnNoiSuy\')); %Duong dan file ham chuc nang
xa = str2double(app.NoiSuy_x.Value); % Danh sách x
ya = str2double(app.NoiSuy_y.Value); % Danh sách y
x = app.NoiSuy_GiaTriNoiSuy.Value; % Giá trị cần nội suy
method = app.NoiSuy_PhuongPhap.Value; % Phương pháp được chọn (Newton hoặc Lagrange)

% Thực hiện nội suy
    switch method
        case 'Newton'
            P = newtoninterpo(xa, ya, x); % Nội suy Newton
            symbolicPoly = NewtonSymbolic(xa, ya); % Tạo đa thức symbolic
        case 'Lagrange'
            P = lagrange(xa, ya, x); % Nội suy Lagrange
            symbolicPoly = LagrangeSymbolic(xa, ya); % Tạo đa thức symbolic
    end

% Hiển thị kết quả
app.NoiSuy_KetQua.Value = sprintf('%.6f', P);
app.NoiSuy_DaThuc.Value = char(symbolicPoly); % Chuyển đa thức symbolic sang chuỗi để hiển thị

% Xóa trục trước đó
cla(app.NoiSuy_DoThi);

% Tạo giá trị để vẽ đường nội suy
xx = linspace(min(xa), max(xa), 100);

% Tính giá trị nội suy
if strcmp(method, 'Newton')
    yy = arrayfun(@(xi) newtoninterpo(xa, ya, xi), xx); % Gọi nội suy Newton
else
    yy = arrayfun(@(xi) lagrange(xa, ya, xi), xx); % Gọi nội suy Lagrange
end

% Vẽ đường nội suy
plot(app.NoiSuy_DoThi, xx, yy, '-b', 'LineWidth', 2);

% Hiển thị dữ liệu gốc
hold(app.NoiSuy_DoThi, 'on');
plot(app.NoiSuy_DoThi, xa, ya, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');

% Hiển thị điểm nội suy
plot(app.NoiSuy_DoThi, x, P, 'gx', 'MarkerSize', 10, 'LineWidth', 2);

% Thêm chú thích và lưới
legend(app.NoiSuy_DoThi, 'Đa thức nội suy', 'Dữ liệu gốc', 'Điểm nội suy');
grid(app.NoiSuy_DoThi, 'on');


disp(xa)
disp(ya)
disp(P)
        end

        % Callback function: NghiemTab, Nghiem_DoThi, Nghiem_KetQua, 
        % Nghiem_Nhapfp, Nghiem_PhuongPhap, Nghiem_SoLanLap
        function Nghiem_GiaTriValueChanged(app, event)
            addpath(fullfile(pwd, '\DoAnTimNghiem\')); % Đường dẫn file chức năng
a = app.Nghiem_KhoangPhanLy_a.Value;
b = app.Nghiem_KhoangPhanLy_b.Value;
saiso = app.Nghiem_SaiSoChoPhep.Value;

% Chuyển chuỗi nhập thành hàm
fx = str2func(['@(x) ' app.Nghiem_NhapPhuongTrinh.Value]);
fp = [];
if ~isempty(app.Nghiem_Nhapfp.Value)
    fp = str2func(['@(x) ' app.Nghiem_Nhapfp.Value]);
end

method = app.Nghiem_PhuongPhap.Value;

% Thực hiện tính nghiệm
switch method
    case 'Chia đôi'
        [P, n] = chiadoi(fx, a, b, saiso);
    case 'Newton'
        [P, n] = tieptuyen(fx, fp, a, b, saiso);
    case 'Lặp'
        [P, n] = lap(fx, fp, a, b, saiso);
end

% Hiển thị kết quả
app.Nghiem_KetQua.Value = sprintf('%.6f', P);
app.Nghiem_SoLanLap.Value = sprintf('%d', n);

% Vẽ đồ thị
xx = linspace(a, b, 100);
yy = arrayfun(fx, xx); % Tính giá trị của hàm fx tại các điểm nội suy

cla(app.Nghiem_DoThi); % Xóa đồ thị cũ
plot(app.Nghiem_DoThi, xx, yy, '-b', 'LineWidth', 2); % Vẽ hàm fx
hold(app.Nghiem_DoThi, 'on');
plot(app.Nghiem_DoThi, [a, b], [fx(a), fx(b)], 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r'); % Dữ liệu gốc
plot(app.Nghiem_DoThi, P, fx(P), 'gx', 'MarkerSize', 10, 'LineWidth', 2); % Hiển thị nghiệm
legend(app.Nghiem_DoThi, 'Hàm f(x)', 'Khoảng ban đầu', 'Nghiệm');
grid(app.Nghiem_DoThi, 'on');
hold(app.Nghiem_DoThi, 'off');
disp(P)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nUIFigure = uifigure('Visible', 'off');
            app.nUIFigure.Name = 'Đồ án';
            app.TabGroup = uitabgroup(app.nUIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.SizeChangedFcn = createCallbackFcn(app, @NghiemTabSizeChanged, true);
            app.NghiemTab.Title = 'Nghiệm';
            app.NghiemTab.ButtonDownFcn = createCallbackFcn(app, @Nghiem_GiaTriValueChanged, true);
            app.Nghiem_DoThi = uiaxes(app.NghiemTab);
            title(app.Nghiem_DoThi, 'Đồ thị hàm số')
            xlabel(app.Nghiem_DoThi, 'X')
            ylabel(app.Nghiem_DoThi, 'Y')
            app.Nghiem_DoThi.ButtonDownFcn = createCallbackFcn(app, @Nghiem_GiaTriValueChanged, true);
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.Nghiem_NhapPhuongTrinh = uieditfield(app.NghiemTab, 'text');
            app.Nghiem_NhapPhuongTrinh.ValueChangedFcn = createCallbackFcn(app, @NghiemTabSizeChanged, true);
            app.Nghiem_NhapPhuongTrinh.Value = 'x + 3';
            app.KhongphnlyabEditFieldLabel = uilabel(app.NghiemTab);
            app.KhongphnlyabEditFieldLabel.Text = 'Khoảng phân ly [a,b]';
            app.Nghiem_KhoangPhanLy_a = uieditfield(app.NghiemTab, 'numeric');
            app.Nghiem_KhoangPhanLy_a.ValueChangedFcn = createCallbackFcn(app, @NghiemTabSizeChanged, true);
            app.Nghiem_KhoangPhanLy_a.Value = 4;
            app.Nghiem_KhoangPhanLy_b = uieditfield(app.NghiemTab, 'numeric');
            app.Nghiem_KhoangPhanLy_b.ValueChangedFcn = createCallbackFcn(app, @NghiemTabSizeChanged, true);
            app.Nghiem_KhoangPhanLy_b.Value = 7;
            app.SaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.Nghiem_SaiSoChoPhep = uieditfield(app.NghiemTab, 'numeric');
            app.Nghiem_SaiSoChoPhep.ValueChangedFcn = createCallbackFcn(app, @NghiemTabSizeChanged, true);
            app.Nghiem_SaiSoChoPhep.Value = 0.01;
            app.PhngphptmDropDownLabel = uilabel(app.NghiemTab);
            app.PhngphptmDropDownLabel.Text = 'Phương pháp tìm';
            app.Nghiem_PhuongPhap = uidropdown(app.NghiemTab);
            app.Nghiem_PhuongPhap.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.Nghiem_PhuongPhap.ValueChangedFcn = createCallbackFcn(app, @Nghiem_GiaTriValueChanged, true);
            app.Nghiem_PhuongPhap.Value = 'Chia đôi';
            app.KtqunghimTextAreaLabel = uilabel(app.NghiemTab);
            app.KtqunghimTextAreaLabel.Text = 'Kết quả nghiệm';
            app.Nghiem_KetQua = uitextarea(app.NghiemTab);
            app.Nghiem_KetQua.ValueChangedFcn = createCallbackFcn(app, @Nghiem_GiaTriValueChanged, true);
            app.Nghiem_KetQua.Editable = 'off';
            app.SlnlpTextAreaLabel = uilabel(app.NghiemTab);
            app.SlnlpTextAreaLabel.Text = 'Số lần lặp';
            app.Nghiem_SoLanLap = uitextarea(app.NghiemTab);
            app.Nghiem_SoLanLap.ValueChangedFcn = createCallbackFcn(app, @Nghiem_GiaTriValueChanged, true);
            app.Nghiem_SoLanLap.Editable = 'off';
            app.NhpfpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpfpEditFieldLabel.Text = 'Nhập fp';
            app.Nghiem_Nhapfp = uieditfield(app.NghiemTab, 'text');
            app.Nghiem_Nhapfp.ValueChangedFcn = createCallbackFcn(app, @Nghiem_GiaTriValueChanged, true);
            app.Nghiem_Nhapfp.Value = '2';
            app.NoiSuyTab = uitab(app.TabGroup);
            app.NoiSuyTab.Title = 'Nội suy';
            app.NoiSuy_DoThi = uiaxes(app.NoiSuyTab);
            title(app.NoiSuy_DoThi, 'Đồ thị hàm số nội suy và giá trị thực')
            xlabel(app.NoiSuy_DoThi, 'X')
            ylabel(app.NoiSuy_DoThi, 'Y')
            app.NoiSuy_DoThi.ButtonDownFcn = createCallbackFcn(app, @NoiSuy_DaThucValueChanged, true);
            app.PhngphpnisuyLabel = uilabel(app.NoiSuyTab);
            app.PhngphpnisuyLabel.Text = 'Phương pháp nội suy';
            app.NoiSuy_PhuongPhap = uidropdown(app.NoiSuyTab);
            app.NoiSuy_PhuongPhap.Items = {'Newton', 'Lagrange'};
            app.NoiSuy_PhuongPhap.ValueChangedFcn = createCallbackFcn(app, @NoiSuy_DaThucValueChanged, true);
            app.NoiSuy_PhuongPhap.Value = 'Newton';
            app.GitrnisuyLabel = uilabel(app.NoiSuyTab);
            app.GitrnisuyLabel.Text = 'Giá trị nội suy';
            app.NoiSuy_GiaTriNoiSuy = uieditfield(app.NoiSuyTab, 'numeric');
            app.NoiSuy_GiaTriNoiSuy.Value = 0.14;
            app.PhngtrnhLabel = uilabel(app.NoiSuyTab);
            app.PhngtrnhLabel.Text = 'Phương trình';
            app.NoiSuy_DaThuc = uitextarea(app.NoiSuyTab);
            app.NoiSuy_DaThuc.ValueChangedFcn = createCallbackFcn(app, @NoiSuy_DaThucValueChanged, true);
            app.KtqunisuyTextAreaLabel = uilabel(app.NoiSuyTab);
            app.KtqunisuyTextAreaLabel.Text = 'Kết quả nội suy';
            app.NoiSuy_KetQua = uitextarea(app.NoiSuyTab);
            app.NoiSuy_KetQua.ValueChangedFcn = createCallbackFcn(app, @NoiSuy_DaThucValueChanged, true);
            app.NhpdliuxyEditFieldLabel_3 = uilabel(app.NoiSuyTab);
            app.NhpdliuxyEditFieldLabel_3.Text = 'Nhập dữ liệu x,y';
            app.NoiSuy_x = uieditfield(app.NoiSuyTab, 'text');
            app.NoiSuy_x.Value = '0.1 0.2 0.3 0.4';
            app.NoiSuy_y = uieditfield(app.NoiSuyTab, 'text');
            app.NoiSuy_y.Value = '0.09983 0.19867 0.29552 0.38942';
            app.HoiQuyTab = uitab(app.TabGroup);
            app.HoiQuyTab.Title = 'Hồi quy';
            app.HoiQuy_DoThi = uiaxes(app.HoiQuyTab);
            title(app.HoiQuy_DoThi, 'Đồ thị hàm số nội suy và giá trị thực')
            xlabel(app.HoiQuy_DoThi, 'X')
            ylabel(app.HoiQuy_DoThi, 'Y')
            app.HoiQuy_y = uieditfield(app.HoiQuyTab, 'numeric');
            app.NhpdliuxyLabel_2 = uilabel(app.HoiQuyTab);
            app.NhpdliuxyLabel_2.Text = 'Nhập dữ liệu x, y';
            app.HoiQuy_x = uieditfield(app.HoiQuyTab, 'numeric');
            app.GitrcnsuyonLabel = uilabel(app.HoiQuyTab);
            app.GitrcnsuyonLabel.Text = 'Giá trị cần suy đoán';
            app.HoiQuy_GiaTriSuyDoan = uieditfield(app.HoiQuyTab, 'numeric');
            app.PhngphphiquyLabel = uilabel(app.HoiQuyTab);
            app.PhngphphiquyLabel.Text = 'Phương pháp hồi quy';
            app.HoiQuy_PhuongPhap = uidropdown(app.HoiQuyTab);
            app.HoiQuy_PhuongPhap.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.HoiQuy_PhuongPhap.Value = 'Tuyến tính';
            app.PhngtrnhLabel_2 = uilabel(app.HoiQuyTab);
            app.PhngtrnhLabel_2.Text = 'Phương trình';
            app.HoiQuy_PhuongTrinh = uitextarea(app.HoiQuyTab);
            app.KtqudonLabel = uilabel(app.HoiQuyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.HoiQuy_KetQua = uitextarea(app.HoiQuyTab);
            app.DaoHamTab = uitab(app.TabGroup);
            app.DaoHamTab.Title = 'Đạo hàm';
            app.NhphmsLabel = uilabel(app.DaoHamTab);
            app.NhphmsLabel.Text = 'Nhập hàm số';
            app.DaoHam_HamSo = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_HamSo.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_HamSo.Value = '1/sqrt(1-x^2)';
            app.GitrcnohmLabel = uilabel(app.DaoHamTab);
            app.GitrcnohmLabel.Text = 'Giá trị cần đạo hàm';
            app.DaoHam_GiaTri = uieditfield(app.DaoHamTab, 'numeric');
            app.DaoHam_GiaTri.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_GiaTri.Value = 0.5;
            app.GitrsaisSwitchLabel = uilabel(app.DaoHamTab);
            app.GitrsaisSwitchLabel.Text = 'Giá trị sai số';
            app.DaoHam_GiaTriSaiSo = uiswitch(app.DaoHamTab, 'slider');
            app.DaoHam_GiaTriSaiSo.Items = {'O(h)', 'O(h^2)'};
            app.DaoHam_GiaTriSaiSo.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_GiaTriSaiSo.Value = 'O(h)';
            app.PhngphpohmLabel = uilabel(app.DaoHamTab);
            app.PhngphpohmLabel.Text = 'Phương pháp đạo hàm';
            app.DaoHam_PhuongPhap = uidropdown(app.DaoHamTab);
            app.DaoHam_PhuongPhap.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DaoHam_PhuongPhap.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_PhuongPhap.Value = 'Xấp xỉ tiến';
            app.KtquTextAreaLabel = uilabel(app.DaoHamTab);
            app.KtquTextAreaLabel.Text = 'Kết quả';
            app.DaoHam_KetQua = uitextarea(app.DaoHamTab);
            app.DaoHam_KetQua.Editable = 'off';
            app.KtquchnhxcLabel = uilabel(app.DaoHamTab);
            app.KtquchnhxcLabel.Text = 'Kết quả chính xác';
            app.DaoHam_KetQuaChinhXac = uitextarea(app.DaoHamTab);
            app.DaoHam_KetQuaChinhXac.Editable = 'off';
            app.NhpdliuxyEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhpdliuxyEditFieldLabel.Text = 'Nhập dữ liệu x, y';
            app.DaoHam_x = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_x.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_x.Value = '0.1 0.3 0.5 0.7 0.9';
            app.DaoHam_y = uieditfield(app.DaoHamTab, 'text');
            app.DaoHam_y.ValueChangedFcn = createCallbackFcn(app, @DaoHam_GiaTriValueChanged, true);
            app.DaoHam_y.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích phân';
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.TichPhan_N = uitextarea(app.TichPhanTab);
            app.PhngphptchphnLabel = uilabel(app.TichPhanTab);
            app.PhngphptchphnLabel.Text = 'Phương pháp tích phân';
            app.TichPhan_PhuongPhap = uidropdown(app.TichPhanTab);
            app.TichPhan_PhuongPhap.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.TichPhan_PhuongPhap.Value = 'Hình thang';
            app.KtquTextAreaLabel_2 = uilabel(app.TichPhanTab);
            app.KtquTextAreaLabel_2.Text = 'Kết quả';
            app.TichPhan_KetQua = uitextarea(app.TichPhanTab);
            app.TichPhan_KetQua.Editable = 'off';
            app.NhpdliuxyEditFieldLabel_2 = uilabel(app.TichPhanTab);
            app.NhpdliuxyEditFieldLabel_2.Text = 'Nhập  dữ liệu x, y';
            app.TichPhan_x = uieditfield(app.TichPhanTab, 'text');
            app.TichPhan_y = uieditfield(app.TichPhanTab, 'text');
            app.NhpcnabLabel = uilabel(app.TichPhanTab);
            app.NhpcnabLabel.Text = 'Nhập cận a, b';
            app.TichPhan_a = uieditfield(app.TichPhanTab, 'text');
            app.TichPhan_b = uieditfield(app.TichPhanTab, 'text');
            app.NhphmsLabel_2 = uilabel(app.TichPhanTab);
            app.NhphmsLabel_2.Text = 'Nhập hàm số';
            app.TichPhan_HamSo = uieditfield(app.TichPhanTab, 'text');
            app.TichPhan_NutNhan = uibutton(app.TichPhanTab, 'push');
            app.TichPhan_NutNhan.ButtonPushedFcn = createCallbackFcn(app, @TichPhan_NValueChanged2, true);
            app.TichPhan_NutNhan.Text = 'Chạy chương trình';
            app.GioiThieuTab = uitab(app.TabGroup);
            app.GioiThieuTab.Title = 'Giới thiệu nhóm';
            % Show the figure after all components are created
            app.nUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nUIFigure)
        end
    end
end